classdef image_segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GaussianBlurStandardDeviationSlider  matlab.ui.control.Slider
        GaussianBlurStandardDeviationSliderLabel  matlab.ui.control.Label
        MorphologyDiskRadiusSlider     matlab.ui.control.Slider
        MorphologyDiskRadiusLabel      matlab.ui.control.Label
        FillThresholdSlider            matlab.ui.control.Slider
        FillThresholdSliderLabel       matlab.ui.control.Label
        EdgeThresholdSlider            matlab.ui.control.Slider
        EdgeThresholdSliderLabel       matlab.ui.control.Label
        EdgeDetectionDropDown          matlab.ui.control.DropDown
        EdgeDetectionDropDownLabel     matlab.ui.control.Label
        FillingDetectionDropDown       matlab.ui.control.DropDown
        FillingDetectionDropDownLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        OriginalImageContainer         matlab.ui.control.UIAxes
        SegmentedImageContainer        matlab.ui.control.UIAxes
        FillingImageContainer          matlab.ui.control.UIAxes
        EdgeImageContainer             matlab.ui.control.UIAxes
        BrowseImageButton              matlab.ui.control.Button
    end

    properties (Access = public)
        original_image
        edge_image
        filling_image
        segmented_image
    end

    methods (Access = public)
        function updateimage(app, fname)
            try
                app.original_image = imread(fname);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            displayImages(app)
        end
        
        function displayImages(app)
            if isempty(app.original_image) 
                return 
            end

            imagesc(app.OriginalImageContainer, app.original_image);
            displayEdge(app);
            displayFilling(app);
            displaySegmented(app);
        end

        function displayEdge(app)
            if isempty(app.original_image) 
                return 
            end

            image = app.original_image;
            mode = app.EdgeDetectionDropDown.Value;
            threshold = round(app.EdgeThresholdSlider.Value);
            type = app.FillingDetectionDropDown.Value;
            sigma = app.GaussianBlurStandardDeviationSlider.Value;
            app.edge_image = detectEdge(image, sigma, mode, threshold, type);
            imagesc(app.EdgeImageContainer, app.edge_image);
        end

        function displayFilling(app)
            if isempty(app.edge_image) 
                return 
            end

            image = app.edge_image;
            app.filling_image = fillImage(image, round(app.MorphologyDiskRadiusSlider.Value), round(app.FillThresholdSlider.Value) * 1000);
            disp(round(app.FillThresholdSlider.Value))
            imagesc(app.FillingImageContainer, app.filling_image);
        end

        function displaySegmented(app)
            if isempty(app.original_image) || isempty(app.edge_image) || isempty(app.filling_image)
                return 
            end

            mask = (app.edge_image & app.filling_image) | app.filling_image;
            mask_image = ~cat(3, mask, mask, mask);
            app.segmented_image = app.original_image;
            app.segmented_image(mask_image) = 0;
            imagesc(app.SegmentedImageContainer, app.segmented_image);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OriginalImageContainer.Colormap = gray(256);
            axis(app.OriginalImageContainer, 'image');
            app.EdgeImageContainer.Colormap = gray(256);
            axis(app.EdgeImageContainer, 'image');
            app.FillingImageContainer.Colormap = gray(256);
            axis(app.FillingImageContainer, 'image');
            app.SegmentedImageContainer.Colormap = gray(256);
            axis(app.SegmentedImageContainer, 'image');
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: EdgeDetectionDropDown
        function EdgeDetectionDropDownValueChanged(app, event)
            displayImages(app);
        end

        % Value changed function: FillingDetectionDropDown
        function FillingDetectionDropDownValueChanged(app, event)
            displayImages(app);
        end

        % Callback function
        function AdvanceButtonValueChanged(app, event)
            
        end

        % Value changed function: EdgeThresholdSlider
        function EdgeThresholdSliderValueChanged(app, event)
            displayImages(app);
        end

        % Value changed function: FillThresholdSlider
        function FillThresholdSliderValueChanged(app, event)
            displayImages(app);
        end

        % Value changed function: MorphologyDiskRadiusSlider
        function MorphologyDiskRadiusSliderValueChanged(app, event)
            displayImages(app);
        end

        % Value changed function: GaussianBlurStandardDeviationSlider
        function GaussianBlurStandardDeviationSliderValueChanged(app, event)
            displayImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BrowseImageButton = uibutton(app.GridLayout, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.EdgeImageContainer = uiaxes(app.GridLayout2);
            title(app.EdgeImageContainer, 'Edge Image')
            zlabel(app.EdgeImageContainer, 'Z')
            app.EdgeImageContainer.XLim = [0 1];
            app.EdgeImageContainer.XTick = [];
            app.EdgeImageContainer.YTick = [];
            app.FillingImageContainer = uiaxes(app.GridLayout2);
            title(app.FillingImageContainer, 'Filling Image')
            zlabel(app.FillingImageContainer, 'Z')
            app.FillingImageContainer.XLim = [0 1];
            app.FillingImageContainer.XTick = [];
            app.FillingImageContainer.YTick = [];
            app.SegmentedImageContainer = uiaxes(app.GridLayout2);
            title(app.SegmentedImageContainer, 'Segmented Image')
            zlabel(app.SegmentedImageContainer, 'Z')
            app.SegmentedImageContainer.XLim = [0 1];
            app.SegmentedImageContainer.XTick = [];
            app.SegmentedImageContainer.YTick = [];
            app.OriginalImageContainer = uiaxes(app.GridLayout2);
            title(app.OriginalImageContainer, 'Original Image')
            app.OriginalImageContainer.XLim = [0 1];
            app.OriginalImageContainer.XTick = [];
            app.OriginalImageContainer.YTick = [];
            app.OriginalImageContainer.ZTick = [];
            app.FillingDetectionDropDownLabel = uilabel(app.GridLayout);
            app.FillingDetectionDropDownLabel.Text = 'Filling Detection';
            app.FillingDetectionDropDown = uidropdown(app.GridLayout);
            app.FillingDetectionDropDown.Items = {'Red', 'Green', 'Blue'};
            app.FillingDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @FillingDetectionDropDownValueChanged, true);
            app.FillingDetectionDropDown.Value = 'Red';
            app.EdgeDetectionDropDownLabel = uilabel(app.GridLayout);
            app.EdgeDetectionDropDownLabel.Text = 'Edge Detection';
            app.EdgeDetectionDropDown = uidropdown(app.GridLayout);
            app.EdgeDetectionDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionDropDownValueChanged, true);
            app.EdgeDetectionDropDown.Value = 'Laplace';
            app.EdgeThresholdSliderLabel = uilabel(app.GridLayout);
            app.EdgeThresholdSliderLabel.Text = 'Edge Threshold';
            app.EdgeThresholdSlider = uislider(app.GridLayout);
            app.EdgeThresholdSlider.Limits = [0 255];
            app.EdgeThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EdgeThresholdSliderValueChanged, true);
            app.EdgeThresholdSlider.Value = 50;
            app.FillThresholdSliderLabel = uilabel(app.GridLayout);
            app.FillThresholdSliderLabel.Text = 'Fill Threshold';
            app.FillThresholdSlider = uislider(app.GridLayout);
            app.FillThresholdSlider.Limits = [0 10];
            app.FillThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @FillThresholdSliderValueChanged, true);
            app.FillThresholdSlider.Value = 7;
            app.MorphologyDiskRadiusLabel = uilabel(app.GridLayout);
            app.MorphologyDiskRadiusLabel.Text = 'Morphology Disk Radius';
            app.MorphologyDiskRadiusSlider = uislider(app.GridLayout);
            app.MorphologyDiskRadiusSlider.ValueChangedFcn = createCallbackFcn(app, @MorphologyDiskRadiusSliderValueChanged, true);
            app.MorphologyDiskRadiusSlider.Value = 40;
            app.GaussianBlurStandardDeviationSliderLabel = uilabel(app.GridLayout);
            app.GaussianBlurStandardDeviationSliderLabel.Text = 'Gaussian Blur Standard Deviation';
            app.GaussianBlurStandardDeviationSlider = uislider(app.GridLayout);
            app.GaussianBlurStandardDeviationSlider.Limits = [0 5];
            app.GaussianBlurStandardDeviationSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianBlurStandardDeviationSliderValueChanged, true);
            app.GaussianBlurStandardDeviationSlider.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end